classdef Parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TrialDocumentationPanel     matlab.ui.container.Panel
        PatientIDPanel              matlab.ui.container.Panel
        PatientID                   matlab.ui.control.EditField
        TrialNamePanel              matlab.ui.container.Panel
        TrialName                   matlab.ui.control.EditField
        SavePathPanel               matlab.ui.container.Panel
        SavePath                    matlab.ui.control.EditField
        TrialPropertiesPanel        matlab.ui.container.Panel
        DurationPanel               matlab.ui.container.Panel
        Duration                    matlab.ui.control.NumericEditField
        SecondsLabel                matlab.ui.control.Label
        SampleRatePanel             matlab.ui.container.Panel
        SampleRate                  matlab.ui.control.NumericEditField
        HzLabel                     matlab.ui.control.Label
        SelectChannelsButton        matlab.ui.control.Button
        AnalysesParametersPanel     matlab.ui.container.Panel
        PreprocessingPanel          matlab.ui.container.Panel
        DownsampleLabel             matlab.ui.control.Label
        Downsample                  matlab.ui.control.NumericEditField
        HzLabel_4                   matlab.ui.control.Label
        CARAnalysis                 matlab.ui.control.CheckBox
        StimulationParametersPanel  matlab.ui.container.Panel
        StimulationONPanel          matlab.ui.container.Panel
        StimSwitch                  matlab.ui.control.Switch
        StimLamp                    matlab.ui.control.Lamp
        StimTrainsLabel             matlab.ui.control.Label
        StimTrains                  matlab.ui.control.NumericEditField
        BlankUnitsLabel             matlab.ui.control.Label
        StimSampleRateLabel         matlab.ui.control.Label
        StimSampleRate              matlab.ui.control.NumericEditField
        HzLabel_2                   matlab.ui.control.Label
        TrainPauseLabel             matlab.ui.control.Label
        TrainPause                  matlab.ui.control.NumericEditField
        SecondsLabel_2              matlab.ui.control.Label
        StimAmpLabel                matlab.ui.control.Label
        StimAmp                     matlab.ui.control.NumericEditField
        uALabel                     matlab.ui.control.Label
        PlotStimulationButton       matlab.ui.control.Button
        PulseFrequLabel             matlab.ui.control.Label
        PulseFrequ                  matlab.ui.control.NumericEditField
        HzLabel_3                   matlab.ui.control.Label
        PulseWidthLabel             matlab.ui.control.Label
        PulseWidth                  matlab.ui.control.NumericEditField
        usLabel_2                   matlab.ui.control.Label
        InterPulseLabel             matlab.ui.control.Label
        InterPulse                  matlab.ui.control.NumericEditField
        usLabel_3                   matlab.ui.control.Label
        ResumeDelayLabel            matlab.ui.control.Label
        ResumeDelay                 matlab.ui.control.NumericEditField
        SecondsLabel_4              matlab.ui.control.Label
        StimStartLabel              matlab.ui.control.Label
        StimStart                   matlab.ui.control.NumericEditField
        SecondsLabel_3              matlab.ui.control.Label
        TrainDurationLabel          matlab.ui.control.Label
        TrainDuration               matlab.ui.control.NumericEditField
        SecondsLabel_5              matlab.ui.control.Label
        OKButton                    matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        active      = true
        outputs
        
        device
        values
        save_out
        allowed     = false
    end
    
    methods (Access = public)
        
        function values = generateDefaultValues(app, replacements)
            defaults.patient_ID  = 'EC';
            defaults.trial_name  = 'Trial_Name';
            defaults.save_path   = [pwd '\'];
            
            defaults.trial_dur   = 600;
            defaults.sample_rate = app.device.sample_rate;
            
            defaults.ds_fs       = 512;
            defaults.car         = true;
            
            defaults.channels    = app.device.channelStruct();
            
            defaults.stimulation                = false;
            defaults.stim_fs                    = app.device.max_fs;
            defaults.stim_settings.start_delay  = 0;
            defaults.stim_settings.trains       = 1;
            defaults.stim_settings.train_dur    = 10;
            defaults.stim_settings.train_off    = 10;
            defaults.stim_settings.resume_delay = 60;
            defaults.stim_settings.amp          = 3000;
            defaults.stim_settings.pf           = 100;
            defaults.stim_settings.pw           = 100;
            defaults.stim_settings.ipd          = 0;
            
            values = mergestructs(defaults, replacements);
        end
    
        function setValues(app, values)
            if nargin < 2
                values = app.values;
            end
            
            app.PatientID.Value      = values.patient_ID;
            app.TrialName.Value      = values.trial_name;
            app.SavePath.Value       = values.save_path;
            
            app.Duration.Value       = values.trial_dur;
            app.SampleRate.Value     = values.sample_rate;
            
            app.Downsample.Value     = values.ds_fs;
            app.CARAnalysis.Value    = values.car;
            
            if values.stimulation
                app.StimSwitch.Value = 'On';
                app.StimLamp.Color   = [1, 0, 0];
            else
                app.StimSwitch.Value = 'Off';
                app.StimLamp.Color   = [.94, .94, .94];
            end
            app.StimSampleRate.Value = values.stim_fs;
            app.StimStart.Value      = values.stim_settings.start_delay;
            app.StimTrains.Value     = values.stim_settings.trains;
            app.TrainDuration.Value  = values.stim_settings.train_dur;
            app.TrainPause.Value     = values.stim_settings.train_off;
            app.ResumeDelay.Value    = values.stim_settings.resume_delay;
            app.StimAmp.Value        = values.stim_settings.amp;
            app.PulseFrequ.Value     = values.stim_settings.pf;
            app.PulseWidth.Value     = values.stim_settings.pw;
            app.InterPulse.Value     = values.stim_settings.ipd;
        end
    
        function values = getValues(app)
            values.patient_ID       = app.PatientID.Value;
            values.trial_name       = app.TrialName.Value;
            values.save_path        = app.SavePath.Value;
            
            values.trial_dur        = app.Duration.Value;
            values.sample_rate      = app.SampleRate.Value;
            
            values.ds_fs            = app.Downsample.Value;
            values.car              = app.CARAnalysis.Value;
            
            values.channels        = app.values.channels;
            
            if strcmp(app.StimSwitch.Value, 'On')
                values.stimulation  = true;
            else
                values.stimulation  = false;
            end
            values.stim_fs                    = app.StimSampleRate.Value;
            values.stim_settings.start_delay  = app.StimStart.Value;
            values.stim_settings.trains       = app.StimTrains.Value;
            values.stim_settings.train_dur    = app.TrainDuration.Value;
            values.stim_settings.train_off    = app.TrainPause.Value;
            values.stim_settings.resume_delay = app.ResumeDelay.Value;
            values.stim_settings.amp          = app.StimAmp.Value;
            values.stim_settings.pf           = app.PulseFrequ.Value;
            values.stim_settings.pw           = app.PulseWidth.Value;
            values.stim_settings.ipd          = app.InterPulse.Value;
        end
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, device, defaults, save_out)
            % ---- Defaults ---- %
            if nargin < 2
                device = offlineDevice('Offline', [], 3000, 24140.625);
            end
            if nargin < 3 || ~isstruct(decvice)
                defaults = struct();
            end
            if nargin < 4
                save_out = true;
            end
            
            % ---- Assign Properties ---- %
            app.device = device;
            app.save_out = save_out;
            
            % ---- Assign Values ---- %
            values = app.generateDefaultValues(defaults);
            app.setValues(values);
            app.values = values;
        end

        % Value changed function: StimSwitch
        function StimSwitchValueChanged(app, event)
            value = app.StimSwitch.Value;
            
            if strcmp(value, 'On')
                app.StimLamp.Color = [1, 0, 0];
            else
                app.StimLamp.Color = [.94, .94, .94];
            end    
        end

        % Button pushed function: SelectChannelsButton
        function SelectChannelsButtonPushed(app, event)
            channels = app.device.selectChannels(app.values.channels);
            
            if channels.allowed
                app.values.channels = channels;
            end
        end

        % Button pushed function: PlotStimulationButton
        function PlotStimulationButtonPushed(app, event)
            stim_rate = app.StimSampleRate.Value;
            stim_dur = app.TrainDuration.Value;
            amp = app.StimAmp.Value;
            pf = app.PulseFrequ.Value;
            pw = app.PulseWidth.Value;
            ipd = app.InterPulse.Value;
            
            y = biphasicTrain(amp, pw, ipd, pf, stim_dur, stim_rate, true);
            ax = axes;
            plot(ax,1:length(y),y);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.allowed = true;
            app.active = false;
            pause(1);
            close(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.active = false;
            pause(1);
            close(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.allowed && app.save_out
                values = app.getValues();
                disp(['Saving Parameteres as: ' values.save_path 'parameters'])
                save([pwd '\parameters'],'-struct','values')
            end
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TrialDocumentationPanel = uipanel(app.UIFigure);
            app.TrialDocumentationPanel.Title = 'Trial Documentation';
            app.PatientIDPanel = uipanel(app.TrialDocumentationPanel);
            app.PatientIDPanel.Title = 'Patient ID';
            app.PatientID = uieditfield(app.PatientIDPanel, 'text');
            app.PatientID.Value = 'EC';
            app.TrialNamePanel = uipanel(app.TrialDocumentationPanel);
            app.TrialNamePanel.Title = 'Trial Name';
            app.TrialName = uieditfield(app.TrialNamePanel, 'text');
            app.TrialName.Value = 'Real-Time Analysis';
            app.SavePathPanel = uipanel(app.TrialDocumentationPanel);
            app.SavePathPanel.Title = 'SavePath';
            app.SavePath = uieditfield(app.SavePathPanel, 'text');
            app.SavePath.Value = 'C:\';
            app.TrialPropertiesPanel = uipanel(app.UIFigure);
            app.TrialPropertiesPanel.Title = 'Trial Properties';
            app.DurationPanel = uipanel(app.TrialPropertiesPanel);
            app.DurationPanel.Title = 'Duration';
            app.Duration = uieditfield(app.DurationPanel, 'numeric');
            app.Duration.Limits = [0 Inf];
            app.Duration.Value = 60;
            app.SecondsLabel = uilabel(app.DurationPanel);
            app.SecondsLabel.Text = 'Seconds';
            app.SampleRatePanel = uipanel(app.TrialPropertiesPanel);
            app.SampleRatePanel.Title = 'Sample Rate';
            app.SampleRate = uieditfield(app.SampleRatePanel, 'numeric');
            app.SampleRate.Limits = [0 Inf];
            app.SampleRate.ValueDisplayFormat = '%11.10f';
            app.SampleRate.Editable = 'off';
            app.SampleRate.Enable = 'off';
            app.HzLabel = uilabel(app.SampleRatePanel);
            app.HzLabel.Text = 'Hz';
            app.SelectChannelsButton = uibutton(app.TrialPropertiesPanel, 'push');
            app.SelectChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectChannelsButtonPushed, true);
            app.SelectChannelsButton.Text = 'Select Channels';
            app.AnalysesParametersPanel = uipanel(app.UIFigure);
            app.AnalysesParametersPanel.Title = 'Analyses Parameters';
            app.PreprocessingPanel = uipanel(app.AnalysesParametersPanel);
            app.PreprocessingPanel.Title = 'Preprocessing';
            app.DownsampleLabel = uilabel(app.PreprocessingPanel);
            app.DownsampleLabel.Text = 'Downsampled Rate';
            app.Downsample = uieditfield(app.PreprocessingPanel, 'numeric');
            app.Downsample.Limits = [0 Inf];
            app.Downsample.Editable = 'off';
            app.HzLabel_4 = uilabel(app.PreprocessingPanel);
            app.HzLabel_4.Text = 'Hz';
            app.CARAnalysis = uicheckbox(app.PreprocessingPanel);
            app.CARAnalysis.Text = 'CAR Analysis';
            app.StimulationParametersPanel = uipanel(app.UIFigure);
            app.StimulationParametersPanel.Title = 'Stimulation Parameters';
            app.StimulationONPanel = uipanel(app.StimulationParametersPanel);
            app.StimulationONPanel.Title = 'Stimulation ON';
            app.StimSwitch = uiswitch(app.StimulationONPanel, 'slider');
            app.StimSwitch.ValueChangedFcn = createCallbackFcn(app, @StimSwitchValueChanged, true);
            app.StimLamp = uilamp(app.StimulationONPanel);
            app.StimTrainsLabel = uilabel(app.StimulationParametersPanel);
            app.StimTrainsLabel.Text = 'Trains Per Stimulation';
            app.StimTrains = uieditfield(app.StimulationParametersPanel, 'numeric');
            app.StimTrains.Limits = [0 Inf];
            app.StimTrains.Value = 10;
            app.BlankUnitsLabel = uilabel(app.StimulationParametersPanel);
            app.BlankUnitsLabel.Text = '';
            app.StimSampleRateLabel = uilabel(app.StimulationParametersPanel);
            app.StimSampleRateLabel.Text = 'Sample Rate';
            app.StimSampleRate = uieditfield(app.StimulationParametersPanel, 'numeric');
            app.StimSampleRate.Limits = [0 Inf];
            app.StimSampleRate.ValueDisplayFormat = '%11.4f';
            app.StimSampleRate.Editable = 'off';
            app.StimSampleRate.Enable = 'off';
            app.HzLabel_2 = uilabel(app.StimulationParametersPanel);
            app.HzLabel_2.Text = 'Hz';
            app.TrainPauseLabel = uilabel(app.StimulationParametersPanel);
            app.TrainPauseLabel.Text = 'Inter-Train Pause';
            app.TrainPause = uieditfield(app.StimulationParametersPanel, 'numeric');
            app.TrainPause.Limits = [0 Inf];
            app.TrainPause.Value = 60;
            app.SecondsLabel_2 = uilabel(app.StimulationParametersPanel);
            app.SecondsLabel_2.Text = 'Seconds';
            app.StimAmpLabel = uilabel(app.StimulationParametersPanel);
            app.StimAmpLabel.Text = 'Pulse Amplitude';
            app.StimAmp = uieditfield(app.StimulationParametersPanel, 'numeric');
            app.StimAmp.Limits = [0 Inf];
            app.StimAmp.Value = 3000;
            app.uALabel = uilabel(app.StimulationParametersPanel);
            app.uALabel.Text = 'uA';
            app.PlotStimulationButton = uibutton(app.StimulationParametersPanel, 'push');
            app.PlotStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotStimulationButtonPushed, true);
            app.PlotStimulationButton.Text = {'Plot Stimulation'; ''};
            app.PulseFrequLabel = uilabel(app.StimulationParametersPanel);
            app.PulseFrequLabel.Text = 'Pulse Frequency';
            app.PulseFrequ = uieditfield(app.StimulationParametersPanel, 'numeric');
            app.PulseFrequ.Limits = [0 Inf];
            app.PulseFrequ.Value = 100;
            app.HzLabel_3 = uilabel(app.StimulationParametersPanel);
            app.HzLabel_3.Text = 'Hz';
            app.PulseWidthLabel = uilabel(app.StimulationParametersPanel);
            app.PulseWidthLabel.Text = 'Pulse Width';
            app.PulseWidth = uieditfield(app.StimulationParametersPanel, 'numeric');
            app.PulseWidth.Limits = [0 Inf];
            app.PulseWidth.Value = 100;
            app.usLabel_2 = uilabel(app.StimulationParametersPanel);
            app.usLabel_2.Text = 'us';
            app.InterPulseLabel = uilabel(app.StimulationParametersPanel);
            app.InterPulseLabel.Text = 'Inter-Pulse Duration';
            app.InterPulse = uieditfield(app.StimulationParametersPanel, 'numeric');
            app.InterPulse.Limits = [0 Inf];
            app.usLabel_3 = uilabel(app.StimulationParametersPanel);
            app.usLabel_3.Text = 'us';
            app.ResumeDelayLabel = uilabel(app.StimulationParametersPanel);
            app.ResumeDelayLabel.Text = 'Resume Delay';
            app.ResumeDelay = uieditfield(app.StimulationParametersPanel, 'numeric');
            app.ResumeDelay.Limits = [0 Inf];
            app.ResumeDelay.Value = 100;
            app.SecondsLabel_4 = uilabel(app.StimulationParametersPanel);
            app.SecondsLabel_4.Text = 'Seconds';
            app.StimStartLabel = uilabel(app.StimulationParametersPanel);
            app.StimStartLabel.Text = 'Start Delay';
            app.StimStart = uieditfield(app.StimulationParametersPanel, 'numeric');
            app.StimStart.Limits = [0 Inf];
            app.SecondsLabel_3 = uilabel(app.StimulationParametersPanel);
            app.SecondsLabel_3.Text = 'Seconds';
            app.TrainDurationLabel = uilabel(app.StimulationParametersPanel);
            app.TrainDurationLabel.Text = 'Train Duration';
            app.TrainDuration = uieditfield(app.StimulationParametersPanel, 'numeric');
            app.TrainDuration.Limits = [0 Inf];
            app.TrainDuration.Value = 10;
            app.SecondsLabel_5 = uilabel(app.StimulationParametersPanel);
            app.SecondsLabel_5.Text = 'Seconds';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Parameters(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end